classdef word_record < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        WordEditFieldLabel    matlab.ui.control.Label
        WordEditField         matlab.ui.control.EditField
        STARTButton           matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        TRAININGLabel         matlab.ui.control.Label
        TrialLabel            matlab.ui.control.Label
        TrialsEditFieldLabel  matlab.ui.control.Label
        TrialsEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            clc
            
            
            app.UIAxes.XTick = [0 10 20 30 40 50];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'};
            app.UIAxes.YTick = [0 1 2 3 4];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'};
            
            app.UIAxes.XGrid = 'on';
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            
                if ~exist('D:\Subvocal\TrainingData\','dir')
                
                    mkdir('D:\Subvocal\TrainingData\');
                
                end
            
            app.TrialLabel.FontColor = [0.000 0.000 0.000];
            
            clear a
            
            filename = string(app.WordEditField.Value);
            
            TF = isspace(filename);
            space_flag = 0;
            
            for p = 1:length(TF)
               
                if TF(p) == 1
                    
                    space_flag = 1;
                    break
                    
                end
                
            end
            
                                   
                if filename == ""
                    
                    uialert(uifigure(), 'Word Field Cannot Be Empty', 'Invalid Input');
                    return
                
                elseif space_flag == 1
                    
                    uialert(uifigure(), 'Word cannot have spaces', 'Whitespace Error');
                    return
                    
                end             
                            
                try
                    a = arduino();
                catch
                    uialert(uifigure(), 'Arduino Not Connected', 'Hardware Not Found');
                    return
                end


            value = str2double(app.TrialsEditField.Value);
                
            filepath = 'D:\Subvocal\TrainingData\';
            
            for j = 1:value
               app.TrialLabel.Text = int2str(j);


               for i = 1:50
                   
                   if readDigitalPin(a, 'D11') == 1 || readDigitalPin(a, 'D10') ==1
                       
                       voltage_array_read(i) = 1.65;
                       
                   else
                       
                       voltage_array_read(i) = readVoltage(a, 'A1');
                       
                   end
                                  
               end
               
               app.UIAxes.XTick = [0 10 20 30 40 50];
               app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'};
               app.UIAxes.YTick = [0 1 2 3 4];
               app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'};
               
               app.UIAxes.Visible = 'on';
               app.UIAxes.XGrid = 'on';
               app.UIAxes.XMinorGrid = 'on';
               app.UIAxes.YGrid = 'on';
               app.UIAxes.YMinorGrid = 'on';
               
               app.TrialLabel.Visible = 'on';
               
               matrix(j,:) = voltage_array_read;
               
               plot(app.UIAxes, voltage_array_read);
               
               pause(2)
               
               cla(app.UIAxes, 'reset');
               
               app.UIAxes.XTick = [0 10 20 30 40 50];
               app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'};
               app.UIAxes.YTick = [0 1 2 3 4];
               app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'};
               
               app.UIAxes.XGrid = 'on';
               app.UIAxes.XMinorGrid = 'on';
               app.UIAxes.YGrid = 'on';
               app.UIAxes.YMinorGrid = 'on';
               
            end


            app.TrialLabel.FontColor = [0.000 1.000 0.000];
            app.TrialLabel.Text = 'RECORDING COMPLETED';
            fullpath = strcat(filepath,filename,'.xls');
            xlswrite(fullpath, matrix);
            pause(2)
            app.TrialLabel.Visible = 'off';
            
        end

        % Value changed function: TrialsEditField
        function TrialsEditFieldValueChanged(app, event)
            
            value_one = app.TrialsEditField.Value;
            
            if str2double(value_one) > 10
            
                value_one = '10';
                app.TrialsEditField.Value = value_one;
                
            elseif str2double(value_one) < 1
                
                value_one = '1';
                app.TrialsEditField.Value = value_one;
                
            elseif strcmp(value_one, '') == 1
                
                value_one = '1';
                app.TrialsEditField.Value = value_one;
                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WordEditFieldLabel = uilabel(app.UIFigure);
            app.WordEditFieldLabel.Text = 'Word';
            app.WordEditField = uieditfield(app.UIFigure, 'text');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signals')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            app.UIAxes.XTick = [0 10 20 30 40 50];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'};
            app.UIAxes.YTick = [0 1 2 3 4];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'};
            app.TRAININGLabel = uilabel(app.UIFigure);
            app.TRAININGLabel.Text = 'TRAINING';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Visible = 'off';
            app.TrialLabel.Text = 'Trial';
            app.TrialsEditFieldLabel = uilabel(app.UIFigure);
            app.TrialsEditFieldLabel.Text = 'Trials';
            app.TrialsEditField = uieditfield(app.UIFigure, 'text');
            app.TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialsEditFieldValueChanged, true);
            app.TrialsEditField.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = word_record

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end