classdef word_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TESTINGLabel     matlab.ui.control.Label
        TESTButton       matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
        OUTPUTLabel      matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        PROCESSINGLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)
            
            clc
            
            cla(app.UIAxes, 'reset');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            
            clear a
            
            try
                a = arduino();
            catch
                uialert(uifigure(), 'Arduino Not Connected', 'Hardware Not Found');
                return
            end
            
            app.EditField.Value = '';

            
            for i = 1:50
                
                voltage_array_test(i) = readVoltage(a, 'A0');
            
            end
            
            plot(app.UIAxes, voltage_array_test)
            
            files = dir('D:\Subvocal\TrainingData\*.xls');
            result = 'Match Not Found';
            
            
            app.PROCESSINGLabel.Visible = 'on';
            
            pause(1)
            
            cla(app.UIAxes, 'reset');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            
            
            for l = 1:length(files)
                
                fileopen = files(l).name;
                excel_file = xlsread(strcat('D:\Subvocal\TrainingData\', fileopen));
                fileopen = fileopen(1:end-4);
                matrix(l).fileopen = excel_file;
                        
            end
            
            for u = 1:length(files)
                fileopen = files(u).name;
                fileopen = fileopen(1:end-4);
                
                noRows = size(matrix(u).fileopen, 1);
                
                for v = 1:noRows
                    
                    [Dist(v),D,k,w] = dtw(matrix(u).fileopen(v,:), voltage_array_test);
                
                end 
                
                minimum(u) = min(Dist);
                min(minimum(u));
                      
            end
            if min(minimum) < 0.3
                pos = find(minimum == min(minimum));
                result = files(pos).name;
                result = result(1:end-4);
            end
            
            app.PROCESSINGLabel.Visible = 'off';
            
            app.EditField.Value = result;
            
            pause(1)
            
            tts(result);
            
            if strcmp(result, 'Match Not Found') == 1
                
               cla(app.UIAxes, 'reset');
               app.UIAxes.XGrid = 'on';
               app.UIAxes.XMinorGrid = 'on';
               app.UIAxes.YGrid = 'on';
               app.UIAxes.YMinorGrid = 'on';
                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TESTINGLabel = uilabel(app.UIFigure);
            app.TESTINGLabel.Text = 'TESTING';
            app.TESTButton = uibutton(app.UIFigure, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Text = 'TEST';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.PROCESSINGLabel = uilabel(app.UIFigure);
            app.PROCESSINGLabel.Visible = 'off';
            app.PROCESSINGLabel.Text = 'PROCESSING...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = word_test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end